classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        BuscarButton              matlab.ui.control.Button
        ImagenenNuevaEscalaLabel  matlab.ui.control.Label
        ImagenOriginalLabel       matlab.ui.control.Label
        NewImage                  matlab.ui.control.Image
        SeleccioneDropDown        matlab.ui.control.DropDown
        SeleccioneDropDownLabel   matlab.ui.control.Label
        OriginalImage             matlab.ui.control.Image
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        file;
        path;
        
        loadedImage;        % imagen cargada como matriz
        imageGpuR;          % array en gpu de las componentes en rojo de la img
        imageGpuG;          % array en gpu de las componentes en verde de la img
        imageGpuB;          % array en gpu de las componentes en azul de la img
        
        transformedImage;   % imagen transformada a la nueva escala de colores
        
    end
    
    methods (Access = public)
        
        function escala1(app)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %% GENERA LA IMAGEN EN LA ESCALA 1 %%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            app.transformedImage = app.loadedImage;
            
            [newImageR, newImageG] = arrayfun(@transformation1, app.imageGpuB);
            newImageR = gather( newImageR ); % Fetch the data back from the GPU
            newImageG = gather( newImageG ); % Fetch the data back from the GPU
                 
            app.transformedImage(:,:,1) = newImageR;
            app.transformedImage(:,:,2) = newImageG;
            
            app.NewImage.ImageSource = app.transformedImage;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % GENERA LA GRÁFICA DE LA ESCALA  DE COLOR EN 3D %%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            duration = 1;
            r = 1;
            
            B = linspace(0, duration, (duration)*5000);
            R = r.*cos(B.*50);
            G = r.*sin(B.*50);
            
            map = [R' G' B'];
            
            
            scatter3(app.UIAxes, map(:,1), map(:,2), map(:,3), 5, map, 'filled');
            title(app.UIAxes, 'Mapeo de colores de la transformación I');
            xlabel(app.UIAxes, 'R')
            ylabel(app.UIAxes, 'G')
            zlabel(app.UIAxes, 'B')
            
        end
        
        
        function escala2(app)
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %% GENERA LA IMAGEN EN LA ESCALA 2 %%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            app.transformedImage = app.loadedImage;
            
            newImage = arrayfun(@transformation2, app.imageGpuR, app.imageGpuG);
            newImage = gather( newImage ); % Fetch the data back from the GPU
            app.transformedImage(:,:,3) = newImage;

          
            app.NewImage.ImageSource = app.transformedImage;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % GENERA LA GRÁFICA DE LA ESCALA  DE COLOR EN 3D %%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            [R2, G2] = meshgrid(0:0.025:1);
            B2 = (sin(R2) + cos(G2.*10).^2)./2;
            
            CO(:,:,1) = R2;
            CO(:,:,2) = G2;
            CO(:,:,3) = B2;
            
            
            surf(app.UIAxes, R2, G2, B2, CO);
            title(app.UIAxes, 'Mapeo de colores de la transformación II');
            xlabel(app.UIAxes, 'R');
            ylabel(app.UIAxes, 'G');
            zlabel(app.UIAxes, 'B');
            
        end

        function escala3(app)
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %% GENERA LA IMAGEN EN LA ESCALA 3 %%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            app.transformedImage = app.loadedImage;
            
            [NegativeRedChannel, NegativeGreenChannel, NegativeBlueChannel] = arrayfun(@transformation3, app.imageGpuR, app.imageGpuG, app.imageGpuB);
            newImageR = gather( NegativeRedChannel ); % Fetch the data back from the GPU
            newImageG = gather( NegativeGreenChannel );
            newImageB = gather( NegativeBlueChannel );
            app.transformedImage(:,:,1) = newImageR;
            app.transformedImage(:,:,2) = newImageG;
            app.transformedImage(:,:,3) = newImageB;
          
            app.NewImage.ImageSource = app.transformedImage;
            
        end
        
        function escala4(app)
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %% GENERA LA IMAGEN EN LA ESCALA 3 %%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            app.transformedImage = app.loadedImage;
            
            [NegativeRedChannel, NegativeGreenChannel, NegativeBlueChannel] = arrayfun(@transformation4, app.imageGpuR, app.imageGpuG, app.imageGpuB);
            newImageR = gather( NegativeRedChannel ); % Fetch the data back from the GPU
            newImageG = gather( NegativeGreenChannel );
            newImageB = gather( NegativeBlueChannel );
            app.transformedImage(:,:,1) = newImageR;
            app.transformedImage(:,:,2) = newImageG;
            app.transformedImage(:,:,3) = newImageB;
          
            app.NewImage.ImageSource = app.transformedImage;
            
        end

        function escala5(app)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %% GENERA LA IMAGEN EN LA ESCALA 1 %%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            app.transformedImage = app.loadedImage;
            
            [newImageR, newImageG] = arrayfun(@transformation5, app.imageGpuB);
            newImageR = gather( newImageR ); % Fetch the data back from the GPU
            newImageG = gather( newImageG ); % Fetch the data back from the GPU
                 
            app.transformedImage(:,:,1) = newImageR;
            app.transformedImage(:,:,2) = newImageG;
            
            app.NewImage.ImageSource = app.transformedImage;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % GENERA LA GRÁFICA DE LA ESCALA  DE COLOR EN 3D %%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            duration = 1;
            
            B = linspace(0, duration, (duration)*5000);
            a = 0.2;
            b = 0.8;
            c = 20.0;
            
            R = (a*sin(c*B)+b).*cos(B)./3;
            G = (a*sin(c*B)+b).*sin(B)./3;
            
            map = [R' G' B'];
            
            
            scatter3(app.UIAxes, map(:,1), map(:,2), map(:,3), 5, map, 'filled');
            title(app.UIAxes, 'Mapeo de colores de la transformación V');
            xlabel(app.UIAxes, 'R')
            ylabel(app.UIAxes, 'G')
            zlabel(app.UIAxes, 'B')
            
        end
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BuscarButton
        function BuscarButtonPushed(app, event)
            [app.file, app.path] = uigetfile({'*.jpg'; '*.png'});
            filepath = fullfile(app.path, app.file);
            
            % muestra la imagen seleccionada 
            disp(filepath);
            
            % carga la imagen seleccionada
            app.loadedImage = imread(filepath);
            
            % actualiza la vista de la imagen seleccionada en la interfaz
            app.OriginalImage.ImageSource = app.loadedImage;
            
            app.imageGpuR = gpuArray( app.loadedImage(:,:,1) );
            app.imageGpuG = gpuArray( app.loadedImage(:,:,2) );
            app.imageGpuB = gpuArray( app.loadedImage(:,:,3) );
            
            SeleccioneDropDownValueChanged(app, event);


        end

        % Value changed function: SeleccioneDropDown
        function SeleccioneDropDownValueChanged(app, event)
            value = app.SeleccioneDropDown.Value;
            disp("Escala seleccionada: " + string(value));
            
            
            if value == 'Escala 1'
                disp("Procesando escala 1 ...");
                escala1(app);
                
                
            elseif value == 'Escala 2'
                disp("Procesando escala 2 ...");
                escala2(app);
                
                
            elseif value == 'Escala 3'
                disp("Procesando escala 3 ...");
                escala3(app)
                
            elseif value == 'Escala 4'
                disp("Procesando escala 4 ...");
                escala4(app)

            elseif value == 'Escala 5'
                disp("Procesando escala 5 ...");
                escala5(app)
            end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.OriginalImage = uiimage(app.UIFigure);
            app.SeleccioneDropDownLabel = uilabel(app.UIFigure);
            app.SeleccioneDropDownLabel.Text = 'Seleccione';
            app.SeleccioneDropDown = uidropdown(app.UIFigure);
            app.SeleccioneDropDown.Items = {'Escala 1', 'Escala 2', 'Escala 3', 'Escala 4', 'Escala 5'};
            app.SeleccioneDropDown.ValueChangedFcn = createCallbackFcn(app, @SeleccioneDropDownValueChanged, true);
            app.SeleccioneDropDown.Value = 'Escala 1';
            app.NewImage = uiimage(app.UIFigure);
            app.ImagenOriginalLabel = uilabel(app.UIFigure);
            app.ImagenOriginalLabel.Text = 'Imagen Original';
            app.ImagenenNuevaEscalaLabel = uilabel(app.UIFigure);
            app.ImagenenNuevaEscalaLabel.Text = 'Imagen en Nueva Escala';
            app.BuscarButton = uibutton(app.UIFigure, 'push');
            app.BuscarButton.ButtonPushedFcn = createCallbackFcn(app, @BuscarButtonPushed, true);
            app.BuscarButton.Text = 'Buscar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end